classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        MainmenuTab                    matlab.ui.container.Tab
        innerPIDonSwitch               matlab.ui.control.RockerSwitch
        innerPIDonSwitch_2Label        matlab.ui.control.Label
        DEditField_2                   matlab.ui.control.NumericEditField
        DEditField_4Label              matlab.ui.control.Label
        IEditField_2                   matlab.ui.control.NumericEditField
        IEditField_4Label              matlab.ui.control.Label
        PEditField_2                   matlab.ui.control.NumericEditField
        PEditField_4Label              matlab.ui.control.Label
        DEditField                     matlab.ui.control.NumericEditField
        DEditField_3Label              matlab.ui.control.Label
        IEditField                     matlab.ui.control.NumericEditField
        IEditField_3Label              matlab.ui.control.Label
        PEditField                     matlab.ui.control.NumericEditField
        PEditField_3Label              matlab.ui.control.Label
        ChangeinnerPIDLabel_2          matlab.ui.control.Label
        ChangeouterPIDLabel_2          matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        Lamp_2Label                    matlab.ui.control.Label
        RunsimulationButton_2          matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        ConfigTab                      matlab.ui.container.Tab
        D_innerEditField               matlab.ui.control.NumericEditField
        D_innerEditFieldLabel          matlab.ui.control.Label
        I_innerEditField               matlab.ui.control.NumericEditField
        I_innerEditFieldLabel          matlab.ui.control.Label
        P_innerEditField               matlab.ui.control.NumericEditField
        P_innerEditFieldLabel          matlab.ui.control.Label
        D_outerEditField               matlab.ui.control.NumericEditField
        DEditField_3Label_2            matlab.ui.control.Label
        I_outerEditField               matlab.ui.control.NumericEditField
        IEditField_3Label_2            matlab.ui.control.Label
        P_outerEditField               matlab.ui.control.NumericEditField
        PEditField_3Label_2            matlab.ui.control.Label
        PidoptimizationSwitch          matlab.ui.control.RockerSwitch
        PidoptimizationSwitchLabel     matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        ResetButton                    matlab.ui.control.Button
        SaveconfigButton               matlab.ui.control.Button
        LoadConfigButton               matlab.ui.control.Button
        XplaneinmEditField_2Label_2    matlab.ui.control.Label
        TimeEditField                  matlab.ui.control.NumericEditField
        TimeEditFieldLabel             matlab.ui.control.Label
        PushEditField                  matlab.ui.control.NumericEditField
        PushEditFieldLabel             matlab.ui.control.Label
        SimulationTimesEditField       matlab.ui.control.NumericEditField
        SimulationTimesEditFieldLabel  matlab.ui.control.Label
        YplaneinmEditField             matlab.ui.control.NumericEditField
        YplaneinmEditFieldLabel        matlab.ui.control.Label
        XplaneinmEditField             matlab.ui.control.NumericEditField
        XplaneinmEditFieldLabel        matlab.ui.control.Label
        TssampletimeinsEditField       matlab.ui.control.NumericEditField
        TssampletimeinsEditFieldLabel  matlab.ui.control.Label
        VelocitykmhEditField           matlab.ui.control.NumericEditField
        VelocitykmhEditFieldLabel      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            evalin("base",'scooterparam');
            ResetButtonPushed(app);
        end

        % Value changed function: PEditField
        function PEditFieldValueChanged(app, event)
            value = app.PEditField.Value;
            assignin('base', 'outer_p',value); %change variable in workspace
        end

        % Value changed function: IEditField
        function IEditFieldValueChanged(app, event)
            value = app.IEditField.Value;
            assignin('base', 'outer_i',value);
        end

        % Value changed function: DEditField
        function DEditFieldValueChanged(app, event)
            value = app.DEditField.Value;
            assignin('base', 'outer_d',value);
        end

        % Button pushed function: RunsimulationButton_2
        function RunsimulationButtonPushed(app, event)
            app.Lamp.Color=[1.00,1.00,0.00];
    
            if(app.PidoptimizationSwitch.Value==1)
              cla(app.UIAxes);
              cla(app.UIAxes2);

              app.P_outerEditField.Value=0;
              app.I_outerEditField.Value=0;
              app.D_outerEditField.Value=0;
              app.P_innerEditField.Value=0;
              app.I_innerEditField.Value=0;
              app.D_innerEditField.Value=0;

              app.SaveconfigButtonPushed;
              PID_optimisation;
              PID_opt=load("best_PID.mat");
              if ~isnan(PID_opt.best_PID(1,1))
                  app.P_outerEditField.Value=PID_opt.best_PID(1,2);
                  app.I_outerEditField.Value=PID_opt.best_PID(1,3);
                  app.D_outerEditField.Value=PID_opt.best_PID(1,4);
                  if(app.innerPIDonSwitch.Value==1)
                      app.P_innerEditField.Value=PID_opt.best_PID(1,5);
                      app.I_innerEditField.Value=PID_opt.best_PID(1,6);
                      app.D_innerEditField.Value=PID_opt.best_PID(1,7);
                  end
                plot(app.UIAxes,PID_opt.bestgraph.steer_angle_CAD);
                plot(app.UIAxes2,PID_opt.bestgraph.roll_angle_CAD);
              else
                  app.P_outerEditField.Value=0;
                  app.I_outerEditField.Value=0;
                  app.D_outerEditField.Value=0;
                  text(app.UIAxes,0.2,0.5,'No asymptotically stable system found')
                  text(app.UIAxes2,0.2,0.5,'No asymptotically stable system found')
                  if(app.innerPIDonSwitch.Value==1)
                      app.P_innerEditField.Value=0;
                      app.I_innerEditField.Value=0;
                      app.D_innerEditField.Value=0;
                  end
              end
            else
                set_param('scootermodelsim','SimMechanicsOpenEditorOnUpdate','on')
                simOut=sim('scootermodelsim'); %start the simulation
                plot(app.UIAxes,simOut.steer_angle_CAD);
                plot(app.UIAxes2,simOut.roll_angle_CAD);
            end
            app.Lamp.Color=[0.00,1.00,0.00];
            
        end

        % Value changed function: PEditField_2
        function PEditField_2ValueChanged(app, event)
            value = app.PEditField_2.Value;
            assignin('base', 'inner_p',value); 
        end

        % Value changed function: IEditField_2
        function IEditField_2ValueChanged(app, event)
            value = app.IEditField_2.Value;
           assignin('base', 'inner_i',value); 
        end

        % Value changed function: DEditField_2
        function DEditField_2ValueChanged(app, event)
            value = app.DEditField_2.Value;
            assignin('base', 'inner_d',value); 
        end

        % Value changed function: innerPIDonSwitch
        function innerPIDonSwitchValueChanged(app, event)
            value = app.innerPIDonSwitch.Value;
            assignin('base', 'PIDswitch',value);
        end

        % Value changed function: XplaneinmEditField
        function XplaneinmEditFieldValueChanged(app, event)
            value = app.XplaneinmEditField.Value;
            assignin('base', 'xPla',value);
        end

        % Value changed function: YplaneinmEditField
        function YplaneinmEditFieldValueChanged(app, event)
            value = app.YplaneinmEditField.Value;
           assignin('base', 'yPla',value);

        end

        % Value changed function: TssampletimeinsEditField
        function TssampletimeinsEditFieldValueChanged(app, event)
            value = app.TssampletimeinsEditField.Value;
            assignin('base', 'Ts',value);
        end

        % Value changed function: VelocitykmhEditField
        function VelocitykmhEditFieldValueChanged(app, event)
            value = app.VelocitykmhEditField.Value;
            r_wheel=evalin("base",'r_wheel'); %gets r_wheel variables from scooterparam
            velocity=value/(r_wheel*3.6);
            assignin('base', 'initvelocity',velocity);

        end

        % Value changed function: SimulationTimesEditField
        function SimulationTimesEditFieldValueChanged(app, event)
            value = app.SimulationTimesEditField.Value;
            assignin('base', 'simulationtime',value);

        end

        % Value changed function: PushEditField
        function PushEditFieldValueChanged(app, event)
            value = app.PushEditField.Value;
           assignin('base', 'pushamp',value);

        end

        % Value changed function: TimeEditField
        function TimeEditFieldValueChanged(app, event)
            value = app.TimeEditField.Value;
             assignin('base', 'phase',value);

        end

        % Button pushed function: LoadConfigButton
        function LoadConfigButtonPushed(app, event)
            Config=load("appconfig.mat");
           app.PEditField.Value= Config.outer_p;
           app.IEditField.Value= Config.outer_i;
           app.DEditField.Value= Config.outer_d;
           app.PEditField_2.Value= Config.inner_p ;
           app.IEditField_2.Value=Config.inner_i;
           app.DEditField_2.Value= Config.inner_d;
           app.innerPIDonSwitch.Value= Config.PIDswitch;
           app.PidoptimizationSwitch.Value= Config.Pidopton;
           app.VelocitykmhEditField.Value= Config.v;
           app.SimulationTimesEditField.Value= Config.simulationtime;
           app.TssampletimeinsEditField.Value= Config.Ts;
           app.XplaneinmEditField.Value= Config.xPla;
           app.YplaneinmEditField.Value= Config.yPla;
           app.PushEditField.Value= Config.pushamp;
           app.TimeEditField.Value= Config.phase;
           assignin('base', 'outer_p',Config.outer_p);
           assignin('base','outer_i',Config.outer_i);
           assignin('base','outer_d',Config.outer_d);
           assignin('base','inner_p',Config.inner_p);
           assignin('base','inner_i',Config.inner_i);
           assignin('base','inner_d',Config.inner_d);
           assignin('base','PIDswitch',Config.PIDswitch);
           assignin('base','Pidopton',Config.Pidopton);
           r_wheel=evalin("base",'r_wheel'); %gets r_wheel variables from scooterparam
           velocity=Config.v/(r_wheel*3.6);
           assignin('base','initvelocity',velocity);
           assignin('base','simulationtime',Config.simulationtime);
           assignin('base','Ts',Config.Ts);
           assignin('base','xPla',Config.xPla);
           assignin('base','yPla',Config.yPla);
           assignin('base','pushamp',Config.pushamp);
           assignin('base','phase',Config.phase);
        
        end

        % Button pushed function: SaveconfigButton
        function SaveconfigButtonPushed(app, event)
            outer_p = app.PEditField.Value;
            outer_i = app.IEditField.Value;
            outer_d = app.DEditField.Value;
            inner_p = app.PEditField_2.Value;
            inner_i = app.IEditField_2.Value;
            inner_d = app.DEditField_2.Value;
            PIDswitch = app.innerPIDonSwitch.Value;
            Pidopton = app.PidoptimizationSwitch.Value;
            v = app.VelocitykmhEditField.Value;
            simulationtime = app.SimulationTimesEditField.Value;
            Ts = app.TssampletimeinsEditField.Value;
            xPla = app.XplaneinmEditField.Value;
            yPla = app.YplaneinmEditField.Value;
            pushamp = app.PushEditField.Value;
            phase = app.TimeEditField.Value;
            save('appconfig.mat',"outer_p","outer_i","outer_d","inner_p","inner_i","inner_d","PIDswitch","Pidopton","v","simulationtime","Ts","xPla","yPla","pushamp","phase");
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
           evalin("base",'scooterparam');
           app.PEditField.Value= evalin("base",'outer_p');
           app.IEditField.Value= evalin("base",'outer_i');
           app.DEditField.Value= evalin("base",'outer_d');
           app.PEditField_2.Value= evalin("base",'inner_p');
           app.IEditField_2.Value=evalin("base",'inner_i');
           app.DEditField_2.Value= evalin("base",'inner_d');
           app.innerPIDonSwitch.Value= evalin("base",'PIDswitch');
           app.PidoptimizationSwitch.Value= evalin("base",'Pidopton');
           app.VelocitykmhEditField.Value= evalin("base",'v');
           app.SimulationTimesEditField.Value= evalin("base",'simulationtime');
           app.TssampletimeinsEditField.Value= evalin("base",'Ts');
           app.XplaneinmEditField.Value= evalin("base",'xPla');
           app.YplaneinmEditField.Value= evalin("base",'yPla');
           app.PushEditField.Value= evalin("base",'pushamp');
           app.TimeEditField.Value= evalin("base",'phase');
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
           %101,36,313,319
for i=1:320
    app.Image.Position(1)=app.Image.Position(1)+1;
    app.Image.Position(2)=app.Image.Position(2)-1;
    pause(0.01);
end
app.Image.Visible="off";
        end

        % Value changed function: PidoptimizationSwitch
        function PidoptimizationSwitchValueChanged(app, event)
            value = app.PidoptimizationSwitch.Value;
            assignin('base', 'Pidopton',value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainmenuTab = uitab(app.TabGroup);
            app.MainmenuTab.Title = 'Main menu';
            app.UIAxes = uiaxes(app.MainmenuTab);
            title(app.UIAxes, 'Steering angle')
            xlabel(app.UIAxes, 'sample (100/s)')
            ylabel(app.UIAxes, 'Steering angle (rad)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.MainmenuTab);
            title(app.UIAxes2, 'Roll angle')
            xlabel(app.UIAxes2, 'sample (100/s)')
            ylabel(app.UIAxes2, 'Roll angle (rad)')
            zlabel(app.UIAxes2, 'Z')
            app.RunsimulationButton_2 = uibutton(app.MainmenuTab, 'push');
            app.RunsimulationButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunsimulationButtonPushed, true);
            app.RunsimulationButton_2.Text = 'Run simulation             ';
            app.Lamp_2Label = uilabel(app.MainmenuTab);
            app.Lamp_2Label.Text = '';
            app.Lamp = uilamp(app.MainmenuTab);
            app.ChangeouterPIDLabel_2 = uilabel(app.MainmenuTab);
            app.ChangeouterPIDLabel_2.Text = 'Change outer PID';
            app.ChangeinnerPIDLabel_2 = uilabel(app.MainmenuTab);
            app.ChangeinnerPIDLabel_2.Text = 'Change inner PID';
            app.PEditField_3Label = uilabel(app.MainmenuTab);
            app.PEditField_3Label.Text = 'P';
            app.PEditField = uieditfield(app.MainmenuTab, 'numeric');
            app.PEditField.ValueChangedFcn = createCallbackFcn(app, @PEditFieldValueChanged, true);
            app.PEditField.Value = 4;
            app.IEditField_3Label = uilabel(app.MainmenuTab);
            app.IEditField_3Label.Text = 'I';
            app.IEditField = uieditfield(app.MainmenuTab, 'numeric');
            app.IEditField.ValueChangedFcn = createCallbackFcn(app, @IEditFieldValueChanged, true);
            app.IEditField.Value = 0.1;
            app.DEditField_3Label = uilabel(app.MainmenuTab);
            app.DEditField_3Label.Text = 'D';
            app.DEditField = uieditfield(app.MainmenuTab, 'numeric');
            app.DEditField.ValueChangedFcn = createCallbackFcn(app, @DEditFieldValueChanged, true);
            app.DEditField.Value = 0.1;
            app.PEditField_4Label = uilabel(app.MainmenuTab);
            app.PEditField_4Label.Text = 'P';
            app.PEditField_2 = uieditfield(app.MainmenuTab, 'numeric');
            app.PEditField_2.ValueChangedFcn = createCallbackFcn(app, @PEditField_2ValueChanged, true);
            app.PEditField_2.Value = 5;
            app.IEditField_4Label = uilabel(app.MainmenuTab);
            app.IEditField_4Label.Text = 'I';
            app.IEditField_2 = uieditfield(app.MainmenuTab, 'numeric');
            app.IEditField_2.ValueChangedFcn = createCallbackFcn(app, @IEditField_2ValueChanged, true);
            app.DEditField_4Label = uilabel(app.MainmenuTab);
            app.DEditField_4Label.Text = 'D';
            app.DEditField_2 = uieditfield(app.MainmenuTab, 'numeric');
            app.DEditField_2.ValueChangedFcn = createCallbackFcn(app, @DEditField_2ValueChanged, true);
            app.DEditField_2.Value = 1;
            app.innerPIDonSwitch_2Label = uilabel(app.MainmenuTab);
            app.innerPIDonSwitch_2Label.Text = {'inner'; 'PID on'};
            app.innerPIDonSwitch = uiswitch(app.MainmenuTab, 'rocker');
            app.innerPIDonSwitch.ItemsData = [0 1];
            app.innerPIDonSwitch.ValueChangedFcn = createCallbackFcn(app, @innerPIDonSwitchValueChanged, true);
            app.innerPIDonSwitch.Value = 0;
            app.ConfigTab = uitab(app.TabGroup);
            app.ConfigTab.Title = 'Config';
            app.VelocitykmhEditFieldLabel = uilabel(app.ConfigTab);
            app.VelocitykmhEditFieldLabel.Text = 'Velocity(km/h)';
            app.VelocitykmhEditField = uieditfield(app.ConfigTab, 'numeric');
            app.VelocitykmhEditField.ValueChangedFcn = createCallbackFcn(app, @VelocitykmhEditFieldValueChanged, true);
            app.VelocitykmhEditField.Value = 8;
            app.TssampletimeinsEditFieldLabel = uilabel(app.ConfigTab);
            app.TssampletimeinsEditFieldLabel.Text = 'Ts(sample time in s)';
            app.TssampletimeinsEditField = uieditfield(app.ConfigTab, 'numeric');
            app.TssampletimeinsEditField.ValueChangedFcn = createCallbackFcn(app, @TssampletimeinsEditFieldValueChanged, true);
            app.TssampletimeinsEditField.Value = 0.01;
            app.XplaneinmEditFieldLabel = uilabel(app.ConfigTab);
            app.XplaneinmEditFieldLabel.Text = 'X(plane in m)';
            app.XplaneinmEditField = uieditfield(app.ConfigTab, 'numeric');
            app.XplaneinmEditField.ValueChangedFcn = createCallbackFcn(app, @XplaneinmEditFieldValueChanged, true);
            app.XplaneinmEditField.Value = 80;
            app.YplaneinmEditFieldLabel = uilabel(app.ConfigTab);
            app.YplaneinmEditFieldLabel.Text = 'Y(plane in m)';
            app.YplaneinmEditField = uieditfield(app.ConfigTab, 'numeric');
            app.YplaneinmEditField.ValueChangedFcn = createCallbackFcn(app, @YplaneinmEditFieldValueChanged, true);
            app.YplaneinmEditField.Value = 40;
            app.SimulationTimesEditFieldLabel = uilabel(app.ConfigTab);
            app.SimulationTimesEditFieldLabel.Text = 'Simulation Time(s)';
            app.SimulationTimesEditField = uieditfield(app.ConfigTab, 'numeric');
            app.SimulationTimesEditField.ValueChangedFcn = createCallbackFcn(app, @SimulationTimesEditFieldValueChanged, true);
            app.SimulationTimesEditField.Value = 10;
            app.PushEditFieldLabel = uilabel(app.ConfigTab);
            app.PushEditFieldLabel.Text = 'Push';
            app.PushEditField = uieditfield(app.ConfigTab, 'numeric');
            app.PushEditField.ValueChangedFcn = createCallbackFcn(app, @PushEditFieldValueChanged, true);
            app.PushEditField.Value = 5;
            app.TimeEditFieldLabel = uilabel(app.ConfigTab);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.ConfigTab, 'numeric');
            app.TimeEditField.ValueChangedFcn = createCallbackFcn(app, @TimeEditFieldValueChanged, true);
            app.TimeEditField.Value = 2;
            app.XplaneinmEditField_2Label_2 = uilabel(app.ConfigTab);
            app.XplaneinmEditField_2Label_2.Text = 'Disturbance:';
            app.LoadConfigButton = uibutton(app.ConfigTab, 'push');
            app.LoadConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigButtonPushed, true);
            app.LoadConfigButton.Text = 'Load Config';
            app.SaveconfigButton = uibutton(app.ConfigTab, 'push');
            app.SaveconfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveconfigButtonPushed, true);
            app.SaveconfigButton.Text = 'Save config';
            app.ResetButton = uibutton(app.ConfigTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Image = uiimage(app.ConfigTab);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'scootgui.png');
            app.PidoptimizationSwitchLabel = uilabel(app.ConfigTab);
            app.PidoptimizationSwitchLabel.Text = {'Pid '; 'optimization'};
            app.PidoptimizationSwitch = uiswitch(app.ConfigTab, 'rocker');
            app.PidoptimizationSwitch.ItemsData = [0 1];
            app.PidoptimizationSwitch.ValueChangedFcn = createCallbackFcn(app, @PidoptimizationSwitchValueChanged, true);
            app.PidoptimizationSwitch.Value = 0;
            app.PEditField_3Label_2 = uilabel(app.ConfigTab);
            app.PEditField_3Label_2.Text = 'P_outer';
            app.P_outerEditField = uieditfield(app.ConfigTab, 'numeric');
            app.P_outerEditField.Editable = 'off';
            app.IEditField_3Label_2 = uilabel(app.ConfigTab);
            app.IEditField_3Label_2.Text = 'I_outer';
            app.I_outerEditField = uieditfield(app.ConfigTab, 'numeric');
            app.I_outerEditField.Editable = 'off';
            app.DEditField_3Label_2 = uilabel(app.ConfigTab);
            app.DEditField_3Label_2.Text = 'D_outer';
            app.D_outerEditField = uieditfield(app.ConfigTab, 'numeric');
            app.D_outerEditField.Editable = 'off';
            app.P_innerEditFieldLabel = uilabel(app.ConfigTab);
            app.P_innerEditFieldLabel.Text = 'P_inner';
            app.P_innerEditField = uieditfield(app.ConfigTab, 'numeric');
            app.P_innerEditField.Editable = 'off';
            app.I_innerEditFieldLabel = uilabel(app.ConfigTab);
            app.I_innerEditFieldLabel.Text = 'I_inner';
            app.I_innerEditField = uieditfield(app.ConfigTab, 'numeric');
            app.I_innerEditField.Editable = 'off';
            app.D_innerEditFieldLabel = uilabel(app.ConfigTab);
            app.D_innerEditFieldLabel.Text = 'D_inner';
            app.D_innerEditField = uieditfield(app.ConfigTab, 'numeric');
            app.D_innerEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end